classdef nurseUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaselinemlminLabel              matlab.ui.control.Label
        BaselinemlminSpinner            matlab.ui.control.Spinner
        PUMPSwitchLabel                 matlab.ui.control.Label
        PUMPSwitch                      matlab.ui.control.Switch
        BolusmlshotSpinnerLabel         matlab.ui.control.Label
        BolusmlshotSpinner              matlab.ui.control.Spinner
        BolusGapminSpinnerLabel         matlab.ui.control.Label
        BolusGapminSpinner              matlab.ui.control.Spinner
        ConfirmButton                   matlab.ui.control.StateButton
        AmountofinjectionLasthourPanel  matlab.ui.container.Panel
        Message                         matlab.ui.control.Label
        Message_11                      matlab.ui.control.Label
        AmountofinjectionLast24hoursPanel  matlab.ui.container.Panel
        Message_2                       matlab.ui.control.Label
        Message_12                      matlab.ui.control.Label
        LasttimegetbolusPanel           matlab.ui.container.Panel
        Message_3                       matlab.ui.control.Label
        BaselineAvailableLampLabel      matlab.ui.control.Label
        BaselineAvailableLamp           matlab.ui.control.Lamp
        SettedbaselinePanel             matlab.ui.container.Panel
        Message_4                       matlab.ui.control.Label
        Message_7                       matlab.ui.control.Label
        SettedbolusPanel                matlab.ui.container.Panel
        Message_5                       matlab.ui.control.Label
        Message_8                       matlab.ui.control.Label
        SettedgapPanel                  matlab.ui.container.Panel
        Message_6                       matlab.ui.control.Label
        Message_9                       matlab.ui.control.Label
        BolusAvailableLampLabel         matlab.ui.control.Label
        BolusAvailableLamp              matlab.ui.control.Lamp
        Message_13                      matlab.ui.control.Label
    end

    
    properties (Access = public)
         pump 
         controlsys
         
         baselineSpeed
         bolusAmountInfo
         bolusGapInfo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.baselineSpeed = 0.01;
            app.bolusAmountInfo = 0.2;
            app.bolusGapInfo = 30;
            app.PUMPSwitch.Enable = 0;
            app.BaselineAvailableLamp.Color = [0.65,0.65,0.65];
            app.BolusAvailableLamp.Color = [0.65,0.65,0.65];
            app.Message_7.Text = 'ml/min';
            app.Message_8.Text = 'ml';
            app.Message_9.Text = 'min';
            app.Message_11.Text = 'ml';
            app.Message_12.Text = 'ml';
        end

        % Value changed function: BaselinemlminSpinner
        function BaselinemlminSpinnerValueChanged(app, event)
            value = app.BaselinemlminSpinner.Value;
            app.baselineSpeed = value;
            if value == app.pump.baseline
                app.Message_13.Text = '';
            else
                app.Message_13.Text = 'Not Confirmed !';
            end
        end

        % Value changed function: BolusmlshotSpinner
        function BolusmlshotSpinnerValueChanged(app, event)
            value = app.BolusmlshotSpinner.Value;
            app.bolusAmountInfo = value;
            if value == app.pump.bolus
                app.Message_13.Text = '';
            else
                app.Message_13.Text = 'Not Confirmed !';
            end
        end

        % Value changed function: BolusGapminSpinner
        function BolusGapminSpinnerValueChanged(app, event)
            value = app.BolusGapminSpinner.Value;
            app.bolusGapInfo = value;
            if value == app.pump.bolusGap
                app.Message_13.Text = '';
            else
                app.Message_13.Text = 'Not Confirmed !';
            end
        end

        % Value changed function: ConfirmButton
        function ConfirmButtonValueChanged(app, event)
            app.controlsys.confirm;
            app.Message_13.Text = '';
        end

        % Value changed function: PUMPSwitch
        function PUMPSwitchValueChanged(app, event)
            value = app.PUMPSwitch.Value;
            switch value
                case 'Off'
                    app.controlsys.turnOff;
                case 'On'
                    app.controlsys.turnOn;
            end
            %app.ConfirmButton.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BaselinemlminLabel = uilabel(app.UIFigure);
            app.BaselinemlminLabel.Text = 'Baseline: ml/min';
            app.BaselinemlminSpinner = uispinner(app.UIFigure);
            app.BaselinemlminSpinner.Step = 0.01;
            app.BaselinemlminSpinner.Limits = [0.01 0.1];
            app.BaselinemlminSpinner.ValueChangedFcn = createCallbackFcn(app, @BaselinemlminSpinnerValueChanged, true);
            app.BaselinemlminSpinner.Value = 0.01;
            app.PUMPSwitchLabel = uilabel(app.UIFigure);
            app.PUMPSwitchLabel.Text = 'PUMP';
            app.PUMPSwitch = uiswitch(app.UIFigure, 'slider');
            app.PUMPSwitch.ValueChangedFcn = createCallbackFcn(app, @PUMPSwitchValueChanged, true);
            app.BolusmlshotSpinnerLabel = uilabel(app.UIFigure);
            app.BolusmlshotSpinnerLabel.Text = 'Bolus: ml/shot';
            app.BolusmlshotSpinner = uispinner(app.UIFigure);
            app.BolusmlshotSpinner.Step = 0.01;
            app.BolusmlshotSpinner.Limits = [0.2 0.5];
            app.BolusmlshotSpinner.ValueChangedFcn = createCallbackFcn(app, @BolusmlshotSpinnerValueChanged, true);
            app.BolusmlshotSpinner.Value = 0.2;
            app.BolusGapminSpinnerLabel = uilabel(app.UIFigure);
            app.BolusGapminSpinnerLabel.Text = 'Bolus Gap: min';
            app.BolusGapminSpinner = uispinner(app.UIFigure);
            app.BolusGapminSpinner.Step = 5;
            app.BolusGapminSpinner.Limits = [30 60];
            app.BolusGapminSpinner.ValueChangedFcn = createCallbackFcn(app, @BolusGapminSpinnerValueChanged, true);
            app.BolusGapminSpinner.Value = 30;
            app.ConfirmButton = uibutton(app.UIFigure, 'state');
            app.ConfirmButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.ConfirmButton.Text = {'Confirm'; ''};
            app.AmountofinjectionLasthourPanel = uipanel(app.UIFigure);
            app.AmountofinjectionLasthourPanel.Title = 'Amount of injection/Last hour';
            app.Message = uilabel(app.AmountofinjectionLasthourPanel);
            app.Message.Text = ' ';
            app.Message_11 = uilabel(app.AmountofinjectionLasthourPanel);
            app.Message_11.Text = ' ';
            app.AmountofinjectionLast24hoursPanel = uipanel(app.UIFigure);
            app.AmountofinjectionLast24hoursPanel.Title = 'Amount of injection/Last 24 hours';
            app.Message_2 = uilabel(app.AmountofinjectionLast24hoursPanel);
            app.Message_2.Text = ' ';
            app.Message_12 = uilabel(app.AmountofinjectionLast24hoursPanel);
            app.Message_12.Text = ' ';
            app.LasttimegetbolusPanel = uipanel(app.UIFigure);
            app.LasttimegetbolusPanel.Title = 'Last time get bolus';
            app.Message_3 = uilabel(app.LasttimegetbolusPanel);
            app.Message_3.Text = ' ';
            app.BaselineAvailableLampLabel = uilabel(app.UIFigure);
            app.BaselineAvailableLampLabel.Text = 'Baseline Available';
            app.BaselineAvailableLamp = uilamp(app.UIFigure);
            app.SettedbaselinePanel = uipanel(app.UIFigure);
            app.SettedbaselinePanel.Title = 'Setted baseline';
            app.Message_4 = uilabel(app.SettedbaselinePanel);
            app.Message_4.Text = ' ';
            app.Message_7 = uilabel(app.SettedbaselinePanel);
            app.Message_7.Text = ' ';
            app.SettedbolusPanel = uipanel(app.UIFigure);
            app.SettedbolusPanel.Title = 'Setted bolus';
            app.Message_5 = uilabel(app.SettedbolusPanel);
            app.Message_5.Text = ' ';
            app.Message_8 = uilabel(app.SettedbolusPanel);
            app.Message_8.Text = ' ';
            app.SettedgapPanel = uipanel(app.UIFigure);
            app.SettedgapPanel.Title = 'Setted gap';
            app.Message_6 = uilabel(app.SettedgapPanel);
            app.Message_6.Text = ' ';
            app.Message_9 = uilabel(app.SettedgapPanel);
            app.Message_9.Text = ' ';
            app.BolusAvailableLampLabel = uilabel(app.UIFigure);
            app.BolusAvailableLampLabel.Text = 'Bolus Available';
            app.BolusAvailableLamp = uilamp(app.UIFigure);
            app.Message_13 = uilabel(app.UIFigure);
            app.Message_13.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nurseUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end