classdef PasswordUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConfirmButton           matlab.ui.control.Button
        StaffIDEditFieldLabel   matlab.ui.control.Label
        StaffIDEditField        matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        PasswordEditField       matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        controlsys
        nurse % Description
        pump
        id
        password
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
          if strcmp(app.nurse.ID,app.id) && strcmp(app.nurse.password,app.password)
             nUI = nurseUI;   %nUI = nurseUI
             nUI.pump= app.pump;
             nUI.controlsys = app.pump.controlsys;
             app.controlsys.nurseUI = nUI;
             app.pump.nUIapp = nUI;
             app.nurse.nUIapp = nUI;
             close(app.UIFigure);
          else
              app.Label.Text = 'Wrong id or password';
          end
        end

        % Value changing function: StaffIDEditField
        function StaffIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.id = changingValue;
            %app.Label.Text = changingValue;
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.password = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.StaffIDEditFieldLabel = uilabel(app.UIFigure);
            app.StaffIDEditFieldLabel.Text = 'Staff ID';
            app.StaffIDEditField = uieditfield(app.UIFigure, 'text');
            app.StaffIDEditField.ValueChangingFcn = createCallbackFcn(app, @StaffIDEditFieldValueChanging, true);
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PasswordUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end