classdef ButtonPanel_e < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_3            matlab.ui.control.StateButton
        Button_2            matlab.ui.control.StateButton
        Button_1            matlab.ui.control.StateButton
        EmergencyButton     matlab.ui.control.StateButton
        openButton          matlab.ui.control.StateButton
        closeButton         matlab.ui.control.StateButton
        ElevatorGaugeLabel  matlab.ui.control.Label
        ElevatorGauge       matlab.ui.control.LinearGauge
        upLampLabel         matlab.ui.control.Label
        upLamp              matlab.ui.control.Lamp
        downLampLabel       matlab.ui.control.Label
        downLamp            matlab.ui.control.Lamp
        EditField           matlab.ui.control.EditField
        doorImage           matlab.ui.control.Image
    end

    
    properties (Access = public)
        num;          %elevator tag
        control;         %ElevatorController.m
        fSensor;         %FloorSensor.m
        status=0;        %0:stop; 1:up; 2:down;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Do nothing.
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            app.Button_3.Enable='off';
            if app.fSensor.getFloor(app.num)>2.99
                pause(0.1);
                app.Button_3.Value=0;
                app.Button_3.Enable='on';
                return;
            end
            app.control.setTask_e(app.num,3);
            %If the while(1) in manage is off, turn it on.
            if app.control.managestatus==0
                app.control.manage();
            end
        end

        % Value changed function: EmergencyButton
        function EmergencyButtonValueChanged(app, event)
            app.EmergencyButton.Enable='off';
            app.control.emerg(app.num);
            app.EmergencyButton.Value=0;
            app.EmergencyButton.Enable='on';
            if app.control.managestatus==0
                app.control.manage();
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            app.Button_2.Enable='off';
            if app.fSensor.getFloor(app.num)>1.99 && app.fSensor.getFloor(app.num)<2.01
                pause(0.1);
                app.Button_2.Value=0;
                app.Button_2.Enable='on';
                return;
            end
            app.control.setTask_e(app.num,2);
            %If the while(1) in manage is off, turn it on.
            if app.control.managestatus==0
                app.control.manage();
            end
        end

        % Value changed function: Button_1
        function Button_1ValueChanged(app, event)
            app.Button_1.Enable='off';
            if app.fSensor.getFloor(app.num)<1.01
                pause(0.1);
                app.Button_1.Value=0;
                app.Button_1.Enable='on';
                return;
            end
            app.control.setTask_e(app.num,1);
            %If the while(1) in manage is off, turn it on.
            if app.control.managestatus==0
                app.control.manage();
            end
        end

        % Value changed function: openButton
        function openButtonValueChanged(app, event)
            app.openButton.Enable="off";
            app.control.e_open(app.num);
            if app.control.managestatus==0
                app.control.manage();
            end
            app.openButton.Value=0;
            app.openButton.Enable="on";
        end

        % Value changed function: closeButton
        function closeButtonValueChanged(app, event)
            app.closeButton.Enable="off";
            app.control.e_close(app.num);
            app.closeButton.Value=0;
            app.closeButton.Enable="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1ValueChanged, true);
            app.Button_1.Text = '1';
            app.EmergencyButton = uibutton(app.UIFigure, 'state');
            app.EmergencyButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyButtonValueChanged, true);
            app.EmergencyButton.Text = 'Emergency';
            app.openButton = uibutton(app.UIFigure, 'state');
            app.openButton.ValueChangedFcn = createCallbackFcn(app, @openButtonValueChanged, true);
            app.openButton.Text = '<|>';
            app.closeButton = uibutton(app.UIFigure, 'state');
            app.closeButton.ValueChangedFcn = createCallbackFcn(app, @closeButtonValueChanged, true);
            app.closeButton.Text = '>|<';
            app.ElevatorGaugeLabel = uilabel(app.UIFigure);
            app.ElevatorGaugeLabel.Text = 'Elevator';
            app.ElevatorGauge = uigauge(app.UIFigure, 'linear');
            app.ElevatorGauge.Limits = [1 3];
            app.ElevatorGauge.MajorTicks = [1 2 3];
            app.ElevatorGauge.MajorTickLabels = {'1', '2', '3'};
            app.ElevatorGauge.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.ElevatorGauge.Value = 1;
            app.upLampLabel = uilabel(app.UIFigure);
            app.upLampLabel.Text = 'up';
            app.upLamp = uilamp(app.UIFigure);
            app.downLampLabel = uilabel(app.UIFigure);
            app.downLampLabel.Text = 'down';
            app.downLamp = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Elevator';
            app.doorImage = uiimage(app.UIFigure);
            app.doorImage.ImageSource = 'close_e.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonPanel_e

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end