classdef ButtonPanel_f2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        downButton           matlab.ui.control.StateButton
        Elevator1GaugeLabel  matlab.ui.control.Label
        Elevator1Gauge       matlab.ui.control.LinearGauge
        Elevator2GaugeLabel  matlab.ui.control.Label
        Elevator2Gauge       matlab.ui.control.LinearGauge
        up1Lamp_2Label       matlab.ui.control.Label
        up1Lamp              matlab.ui.control.Lamp
        up2LampLabel         matlab.ui.control.Label
        up2Lamp              matlab.ui.control.Lamp
        upButton             matlab.ui.control.StateButton
        down1LampLabel       matlab.ui.control.Label
        down1Lamp            matlab.ui.control.Lamp
        down2Lamp_2Label     matlab.ui.control.Label
        down2Lamp            matlab.ui.control.Lamp
        EditField            matlab.ui.control.EditField
        door1Image           matlab.ui.control.Image
        door2Image           matlab.ui.control.Image
    end

    
    properties (Access = public)
        control;         %ElevatorController.m
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: upButton
        function upButtonValueChanged(app, event)
            app.upButton.Enable='off';
            app.control.setTask_f(2);
            %If the while(1) in manage is off, turn it on.
            if app.control.managestatus==0
                app.control.manage();
            end
        end

        % Value changed function: downButton
        function downButtonValueChanged(app, event)
            app.downButton.Enable='off';
            app.control.setTask_f(3);
            %If the while(1) in manage is off, turn it on.
            if app.control.managestatus==0
                app.control.manage();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.downButton = uibutton(app.UIFigure, 'state');
            app.downButton.ValueChangedFcn = createCallbackFcn(app, @downButtonValueChanged, true);
            app.downButton.Text = 'down';
            app.Elevator1GaugeLabel = uilabel(app.UIFigure);
            app.Elevator1GaugeLabel.Text = 'Elevator1';
            app.Elevator1Gauge = uigauge(app.UIFigure, 'linear');
            app.Elevator1Gauge.Limits = [1 3];
            app.Elevator1Gauge.MajorTicks = [1 2 3];
            app.Elevator1Gauge.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.Elevator1Gauge.Value = 1;
            app.Elevator2GaugeLabel = uilabel(app.UIFigure);
            app.Elevator2GaugeLabel.Text = 'Elevator2';
            app.Elevator2Gauge = uigauge(app.UIFigure, 'linear');
            app.Elevator2Gauge.Limits = [1 3];
            app.Elevator2Gauge.MajorTicks = [1 2 3];
            app.Elevator2Gauge.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.Elevator2Gauge.Value = 1;
            app.up1Lamp_2Label = uilabel(app.UIFigure);
            app.up1Lamp_2Label.Text = 'up1';
            app.up1Lamp = uilamp(app.UIFigure);
            app.up2LampLabel = uilabel(app.UIFigure);
            app.up2LampLabel.Text = 'up2';
            app.up2Lamp = uilamp(app.UIFigure);
            app.upButton = uibutton(app.UIFigure, 'state');
            app.upButton.ValueChangedFcn = createCallbackFcn(app, @upButtonValueChanged, true);
            app.upButton.Text = 'up';
            app.down1LampLabel = uilabel(app.UIFigure);
            app.down1LampLabel.Text = 'down1';
            app.down1Lamp = uilamp(app.UIFigure);
            app.down2Lamp_2Label = uilabel(app.UIFigure);
            app.down2Lamp_2Label.Text = 'down2';
            app.down2Lamp = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Floor 2';
            app.door1Image = uiimage(app.UIFigure);
            app.door1Image.ImageSource = 'close_f.png';
            app.door2Image = uiimage(app.UIFigure);
            app.door2Image.ImageSource = 'close_f.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonPanel_f2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end