classdef WinWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        m_mainMenu % Description
        m_chessboard
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainMenu, chessboard)
            app.m_mainMenu = mainMenu;
            app.m_chessboard = chessboard;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app.m_mainMenu);
            main = mainMenu(app.m_chessboard);
            app.m_chessboard.mainMenu = main;
            delete(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.m_mainMenu);
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '你赢了！';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '再来一局？';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '是';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '否';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WinWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end