classdef mainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        GridLayout  matlab.ui.container.GridLayout
        Cao         matlab.ui.control.Button
        ZhangFei    matlab.ui.control.Button
        MaChao      matlab.ui.control.Button
        ZhaoYun     matlab.ui.control.Button
        HuangZhong  matlab.ui.control.Button
        GuanYu      matlab.ui.control.Button
        Soldier_1   matlab.ui.control.Button
        Soldier_2   matlab.ui.control.Button
        Soldier_3   matlab.ui.control.Button
        Soldier_4   matlab.ui.control.Button
        Up          matlab.ui.control.Button
        Down        matlab.ui.control.Button
        Left        matlab.ui.control.Button
        Right       matlab.ui.control.Button
        Reset       matlab.ui.control.Button
        StepCount   matlab.ui.control.Label
        RetMain     matlab.ui.control.Button
    end

    properties (Access = private)
        chesscode % Description
        m_chessboard

        step = 0;
        startcode = 1;
    end
    
    methods (Access = public)
        
        function moveVisual(app,chesscode,dir)
            switch(dir)
                case 0
                    chesscode.Layout.Row = chesscode.Layout.Row+1;
                case 1
                    chesscode.Layout.Row = chesscode.Layout.Row-1;
                case 2
                    chesscode.Layout.Column = chesscode.Layout.Column-1;
                case 3
                    chesscode.Layout.Column = chesscode.Layout.Column+1;
                otherwise
                    % do nothing
            end
        end
        
        function CaoWins(app)
            if app.Cao.Layout.Row(1) == 4 && app.Cao.Layout.Row(2) == 5
                if app.Cao.Layout.Column(1) == 2 && app.Cao.Layout.Column(2) == 3
                    Win = WinWindow(app,app.m_chessboard);
                    app.m_chessboard.WinUI = Win;
                end
            end
        end
        
        function InitAll(app)
            
            app.Cao.Layout.Row = app.m_chessboard.Cao_row;
            app.Cao.Layout.Column = app.m_chessboard.Cao_col;
            app.ZhangFei.Layout.Row = app.m_chessboard.ZhangFei_row;
            app.ZhangFei.Layout.Column = app.m_chessboard.ZhangFei_col;
            app.ZhaoYun.Layout.Row = app.m_chessboard.ZhaoYun_row;
            app.ZhaoYun.Layout.Column = app.m_chessboard.ZhaoYun_col;
            app.HuangZhong.Layout.Row = app.m_chessboard.HuangZhong_row;
            app.HuangZhong.Layout.Column = app.m_chessboard.HuangZhong_col;
            app.MaChao.Layout.Row = app.m_chessboard.MaChao_row;
            app.MaChao.Layout.Column = app.m_chessboard.MaChao_col;
            app.GuanYu.Layout.Row = app.m_chessboard.GuanYu_row;
            app.GuanYu.Layout.Column = app.m_chessboard.GuanYu_col;
            app.Soldier_1.Layout.Row = app.m_chessboard.Soldier_1_row;
            app.Soldier_1.Layout.Column = app.m_chessboard.Soldier_1_col;
            app.Soldier_2.Layout.Row = app.m_chessboard.Soldier_2_row;
            app.Soldier_2.Layout.Column = app.m_chessboard.Soldier_2_col;
            app.Soldier_3.Layout.Row = app.m_chessboard.Soldier_3_row;
            app.Soldier_3.Layout.Column = app.m_chessboard.Soldier_3_col;
            app.Soldier_4.Layout.Row = app.m_chessboard.Soldier_4_row;
            app.Soldier_4.Layout.Column = app.m_chessboard.Soldier_4_col;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chessboard, startcode)
            app.startcode = startcode;
            app.m_chessboard = chessboard;
            InitAll(app);
            if(app.startcode == 4)
                app.ZhangFei.Icon = 'ZhangFei_Heng.png';
                app.ZhaoYun.Icon = 'ZhaoYun_Heng.png';
            elseif(app.startcode == 5)
                app.ZhangFei.Icon = 'ZhangFei_Heng.png';
                app.ZhaoYun.Icon = 'ZhaoYun_Heng.png';
            elseif(app.startcode == 6)
                app.ZhangFei.Icon = 'ZhangFei_Heng.png';
            elseif(app.startcode == 7)
                app.ZhangFei.Icon = 'ZhangFei_Heng.png';
                app.ZhaoYun.Icon = 'ZhaoYun_Heng.png';
                app.HuangZhong.Icon = 'HuangZhong_Heng.png';
                app.MaChao.Icon = 'MaChao_Heng.png';
            elseif(app.startcode == 8)
                app.HuangZhong.Icon = 'HuangZhong_Heng.png';
            elseif(app.startcode == 9)
                app.ZhangFei.Icon = 'ZhangFei_Heng.png';
            end
        end

        % Button pushed function: Cao
        function CaoButtonPushed(app, event)
            app.chesscode = app.Cao;
        end

        % Button pushed function: ZhangFei
        function ZhangFeiButtonPushed(app, event)
            app.chesscode = app.ZhangFei;
        end

        % Button pushed function: MaChao
        function MaChaoButtonPushed(app, event)
            app.chesscode = app.MaChao;
        end

        % Button pushed function: ZhaoYun
        function ZhaoYunButtonPushed(app, event)
            app.chesscode = app.ZhaoYun;
        end

        % Button pushed function: HuangZhong
        function HuangZhongButtonPushed(app, event)
            app.chesscode = app.HuangZhong;
        end

        % Button pushed function: GuanYu
        function GuanYuButtonPushed(app, event)
            app.chesscode = app.GuanYu;
        end

        % Button pushed function: Soldier_1
        function Soldier_1ButtonPushed(app, event)
            app.chesscode = app.Soldier_1;
        end

        % Button pushed function: Soldier_2
        function Soldier_2ButtonPushed(app, event)
            app.chesscode = app.Soldier_2;
        end

        % Button pushed function: Soldier_3
        function Soldier_3ButtonPushed(app, event)
            app.chesscode = app.Soldier_3;
        end

        % Button pushed function: Soldier_4
        function Soldier_4ButtonPushed(app, event)
            app.chesscode = app.Soldier_4;
        end

        % Button pushed function: Up
        function UpButtonPushed(app, event)
            %mat = app.m_chessboard.matrix
            %row = app.chesscode.Layout
            if app.m_chessboard.upCheck(app.chesscode.Layout.Row,app.chesscode.Layout.Column)
                app.m_chessboard.upMove(app.chesscode.Layout.Row, app.chesscode.Layout.Column);
                moveVisual(app,app.chesscode, 1);
                app.step = app.step + 1;
                set(app.StepCount,'Text',['目前步数: ', num2str(app.step)] );
            end
            CaoWins(app);
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            %mat = app.m_chessboard.matrix
            %row = app.chesscode.Layout
            if app.m_chessboard.downCheck(app.chesscode.Layout.Row, app.chesscode.Layout.Column)
                app.m_chessboard.downMove(app.chesscode.Layout.Row, app.chesscode.Layout.Column);
                moveVisual(app,app.chesscode, 0);
                app.step = app.step + 1;
                set(app.StepCount,'Text',['目前步数: ', num2str(app.step)] );
            end
            CaoWins(app);
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            %mat = app.m_chessboard.matrix
            %row = app.chesscode.Layout
            if app.m_chessboard.leftCheck(app.chesscode.Layout.Row, app.chesscode.Layout.Column)
                app.m_chessboard.leftMove(app.chesscode.Layout.Row, app.chesscode.Layout.Column);
                moveVisual(app,app.chesscode, 2);
                app.step = app.step + 1;
                set(app.StepCount,'Text',['目前步数: ', num2str(app.step)] );
            end
            CaoWins(app);
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            %mat = app.m_chessboard.matrix
            %row = app.chesscode.Layout
            if app.m_chessboard.rightCheck(app.chesscode.Layout.Row,app.chesscode.Layout.Column)
                app.m_chessboard.rightMove(app.chesscode.Layout.Row, app.chesscode.Layout.Column);
                moveVisual(app,app.chesscode, 3);
                app.step = app.step + 1;
                set(app.StepCount,'Text',['目前步数: ', num2str(app.step)] );
            end
            CaoWins(app);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            InitAll(app);
            app.m_chessboard.matrix = app.m_chessboard.Init_Chessboard;
            app.step = 0;
            set(app.StepCount,'Text',['目前步数: ', num2str(app.step)] );
        end

        % Button pushed function: RetMain
        function RetMainButtonPushed(app, event)
            mainMenu(app.m_chessboard);
            app.m_chessboard
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Cao = uibutton(app.GridLayout, 'push');
            app.Cao.ButtonPushedFcn = createCallbackFcn(app, @CaoButtonPushed, true);
            app.Cao.Icon = 'Cao.png';
            app.Cao.Text = '';
            app.ZhangFei = uibutton(app.GridLayout, 'push');
            app.ZhangFei.ButtonPushedFcn = createCallbackFcn(app, @ZhangFeiButtonPushed, true);
            app.ZhangFei.Icon = 'ZhangFei.png';
            app.ZhangFei.Text = '';
            app.MaChao = uibutton(app.GridLayout, 'push');
            app.MaChao.ButtonPushedFcn = createCallbackFcn(app, @MaChaoButtonPushed, true);
            app.MaChao.Icon = 'MaChao.png';
            app.MaChao.Text = '';
            app.ZhaoYun = uibutton(app.GridLayout, 'push');
            app.ZhaoYun.ButtonPushedFcn = createCallbackFcn(app, @ZhaoYunButtonPushed, true);
            app.ZhaoYun.Icon = 'ZhaoYun.png';
            app.ZhaoYun.Text = '';
            app.HuangZhong = uibutton(app.GridLayout, 'push');
            app.HuangZhong.ButtonPushedFcn = createCallbackFcn(app, @HuangZhongButtonPushed, true);
            app.HuangZhong.Icon = 'HuangZhong.png';
            app.HuangZhong.Text = '';
            app.GuanYu = uibutton(app.GridLayout, 'push');
            app.GuanYu.ButtonPushedFcn = createCallbackFcn(app, @GuanYuButtonPushed, true);
            app.GuanYu.Icon = 'GuanYu.png';
            app.GuanYu.Text = '';
            app.Soldier_1 = uibutton(app.GridLayout, 'push');
            app.Soldier_1.ButtonPushedFcn = createCallbackFcn(app, @Soldier_1ButtonPushed, true);
            app.Soldier_1.Icon = 'Soldier.png';
            app.Soldier_1.Text = '';
            app.Soldier_2 = uibutton(app.GridLayout, 'push');
            app.Soldier_2.ButtonPushedFcn = createCallbackFcn(app, @Soldier_2ButtonPushed, true);
            app.Soldier_2.Icon = 'Soldier.png';
            app.Soldier_2.Text = '';
            app.Soldier_3 = uibutton(app.GridLayout, 'push');
            app.Soldier_3.ButtonPushedFcn = createCallbackFcn(app, @Soldier_3ButtonPushed, true);
            app.Soldier_3.Icon = 'Soldier.png';
            app.Soldier_3.Text = '';
            app.Soldier_4 = uibutton(app.GridLayout, 'push');
            app.Soldier_4.ButtonPushedFcn = createCallbackFcn(app, @Soldier_4ButtonPushed, true);
            app.Soldier_4.Icon = 'Soldier.png';
            app.Soldier_4.Text = '';
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.Up.Text = '上';
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '下';
            app.Left = uibutton(app.UIFigure, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '左';
            app.Right = uibutton(app.UIFigure, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '右';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '重置';
            app.StepCount = uilabel(app.UIFigure);
            app.StepCount.Text = '目前步数: 0';
            app.RetMain = uibutton(app.UIFigure, 'push');
            app.RetMain.ButtonPushedFcn = createCallbackFcn(app, @RetMainButtonPushed, true);
            app.RetMain.Text = '返回主菜单';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainInterface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end