classdef mainMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        Start           matlab.ui.control.Button
        Image           matlab.ui.control.Image
        Representation  matlab.ui.control.Label
        User            matlab.ui.control.Button
    end

    
    properties (Access = public)
        m_chessboard;
        m_code = 1;
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = public)
        
        function code = assignChess(app)
            if(app.DropDown.Value == '横刀立马')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 0, 0, 1], ...
                    [1,2],[2,3],[1,2],1,[3,4],1,[3,4],4,[1,2],4,3,[2,3],5,1,4,2,4,3,5,4)
                code = 1;
            elseif(app.DropDown.Value == '左右布兵')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;0, 1, 1, 0;1, 1, 1, 1;1, 1, 1, 1], ...
                    [1,2],[2,3],[1,2],1,[1,2],4,[3,4],2,[3,4],3,5,[2,3],4,1,4,4,5,1,5,4);
                code = 2;
            elseif(app.DropDown.Value == '兵分三路')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 0, 0, 1], ...
                    [1,2],[2,3],[2,3],1,[2,3],4,[4,5],1,[4,5],4,3,[2,3],1,1,1,4,4,2,4,3);
                code = 3;
            elseif(app.DropDown.Value == '兵来将阻')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;0, 1, 1, 0], ...
                    [1,2],[2,3],5,[2,3],4,[2,3],[2,3],1,[2,3],4,3,[2,3],1,1,1,4,4,1,4,4);
                code = 4;
            elseif(app.DropDown.Value == '层层设防')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;0, 1, 1, 0], ...
                    [1,2],[2,3],4,[3,4],5,[2,3],[3,4],1,[3,4],2,3,[3,4],1,1,1,4,2,1,2,4);
                code = 5;
            elseif(app.DropDown.Value == '插翅难飞')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 0, 1;1, 1, 0, 1;1, 1, 1, 1], ...
                    [1,2],[2,3],5,[3,4],[1,2],1,[1,2],4,[3,4],2,5,[1,2],3,1,3,4,4,1,4,4);
                code = 6;
            elseif(app.DropDown.Value == '过五关 ')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;0, 1, 1, 0], ...
                    [1,2],[2,3],3,[1,2],3,[3,4],4,[3,4],4,[1,2],5,[2,3],1,1,1,4,2,1,2,4);
                code = 7;
            elseif(app.DropDown.Value == '近在咫尺')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;0, 0, 1, 1], ...
                    [4,5],[3,4],[1,2],2,[1,2],3,3,[1,2],[1,2],4,4,[1,2],1,1,2,1,3,3,3,4);
                code = 8;
            elseif(app.DropDown.Value == '前挡后阻')
                app.m_chessboard.InitAttributes([1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;1, 1, 1, 1;0, 1, 1, 0], ...
                    [1,2],[1,2],5,[2,3],[3,4],1,[3,4],2,[2,3],3,1,[3,4],2,4,3,4,4,4,4,3);
                code = 9;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chessboard)
            app.m_chessboard = chessboard;
        end

        % Button pushed function: Start
        function StartPushed(app, event)
            app.m_code = assignChess(app);
            mainUI = mainInterface(app.m_chessboard,app.m_code);
            app.m_chessboard.mainInterfaceUI = mainUI;
            delete(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(value == '横刀立马')
                set(app.Representation,'Text','当前关卡：横刀立马');
                app.Image.ImageSource = 'HengDaoLiMa.png';
            elseif(value == '左右布兵')
                set(app.Representation,'Text','当前关卡：左右布兵');
                app.Image.ImageSource = 'ZuoYouBuBing.png';
            elseif(value == '兵分三路')
                set(app.Representation,'Text','当前关卡：兵分三路');
                app.Image.ImageSource = 'BingFenSanLu.png';
            elseif(value == '兵来将阻')
                set(app.Representation,'Text','当前关卡：兵来将阻');
                app.Image.ImageSource = 'BingLaiJiangZu.png';
            elseif(value == '层层设防')
                set(app.Representation,'Text','当前关卡：层层设防');
                app.Image.ImageSource = 'CengCengSheFang.png';
            elseif(value == '插翅难飞')
                set(app.Representation,'Text','当前关卡：插翅难飞');
                app.Image.ImageSource = 'ChaChiNanFei.png';
            elseif(value == '过五关 ')
                set(app.Representation,'Text','当前关卡：过五关');
                app.Image.ImageSource = 'GuoWuGuan.png';
            elseif(value == '近在咫尺')
                set(app.Representation,'Text','当前关卡：近在咫尺');
                app.Image.ImageSource = 'JinZaiZhiChi.png';
            elseif(value == '前挡后阻')
                set(app.Representation,'Text','当前关卡：前挡后阻');
                app.Image.ImageSource = 'QianDangHouZu.png';
            end
        end

        % Button pushed function: User
        function UserButtonPushed(app, event)
            selfmenu = selfMenu(app.m_chessboard);
            app.m_chessboard.selfUI = selfmenu;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择关卡';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'横刀立马', '左右布兵', '兵分三路', '兵来将阻', '层层设防', '插翅难飞', '过五关 ', '近在咫尺', '前挡后阻'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '横刀立马';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = '开始';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'HengDaoLiMa.png';
            app.Representation = uilabel(app.UIFigure);
            app.Representation.Text = '当前关卡：横刀立马';
            app.User = uibutton(app.UIFigure, 'push');
            app.User.ButtonPushedFcn = createCallbackFcn(app, @UserButtonPushed, true);
            app.User.Text = '自定义';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end