classdef selfMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        B1_1         matlab.ui.control.Button
        B2_1         matlab.ui.control.Button
        B3_1         matlab.ui.control.Button
        B4_1         matlab.ui.control.Button
        B5_1         matlab.ui.control.Button
        B1_2         matlab.ui.control.Button
        B2_2         matlab.ui.control.Button
        B3_2         matlab.ui.control.Button
        B4_2         matlab.ui.control.Button
        B5_2         matlab.ui.control.Button
        B1_3         matlab.ui.control.Button
        B2_3         matlab.ui.control.Button
        B3_3         matlab.ui.control.Button
        B4_3         matlab.ui.control.Button
        B5_3         matlab.ui.control.Button
        B1_4         matlab.ui.control.Button
        B2_4         matlab.ui.control.Button
        B3_4         matlab.ui.control.Button
        B4_4         matlab.ui.control.Button
        B5_4         matlab.ui.control.Button
        StartGame    matlab.ui.control.Button
        Reset        matlab.ui.control.Button
        CurrentRole  matlab.ui.control.Label
        Panel_2      matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        Cao          matlab.ui.control.Image
        ZhangFei     matlab.ui.control.Image
        ZhaoYun      matlab.ui.control.Image
        HuangZhong   matlab.ui.control.Image
        MaChao       matlab.ui.control.Image
        GuanYu       matlab.ui.control.Image
        Soldier_1    matlab.ui.control.Image
        Soldier_2    matlab.ui.control.Image
        Soldier_3    matlab.ui.control.Image
        Soldier_4    matlab.ui.control.Image
        RetMainMenu  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Cao_piece = [2,2,5,4];
        ZhangFei_piece = [2,1,5,5];
        ZhaoYun_piece = [2,1,5,5];
        HuangZhong_piece = [2,1,5,5];
        MaChao_piece = [2,1,5,5];
        GuanYu_piece = [1,2,6,4];
        Soldier_1_piece = [1,1,6,5];
        Soldier_2_piece = [1,1,6,5];
        Soldier_3_piece = [1,1,6,5];
        Soldier_4_piece = [1,1,6,5];
        
        m_chessboard
        
        Button_matrix
        chess_code = 1;
    end
    
    methods (Access = private)

        function button = returnButton(app,row,col)
            button = app.Button_matrix(row,col);
        end
        
        function info = MatchInfo(app,code)
            if(code == 1)
                info = app.Cao_piece;
            elseif(code == 2)
                info = app.ZhangFei_piece;
            elseif(code == 3)
                info = app.ZhaoYun_piece;
            elseif(code == 4)
                info = app.HuangZhong_piece;
            elseif(code == 5)
                info = app.MaChao_piece;
            elseif(code == 6)
                info = app.GuanYu_piece;
            elseif(code == 7)
                info = app.Soldier_1_piece;
            elseif(code == 8)
                info = app.Soldier_2_piece;
            elseif(code == 9)
                info = app.Soldier_3_piece;
            elseif(code == 10)
                info = app.Soldier_4_piece;
            end
        end
        
        function not_approved = Fail(app,row,col,code)
            not_approved = 0;
            info = MatchInfo(app,code);
            if(row < info(3) && col < info(4))
                for m = 0:1:(info(1)-1)
                    for n = 0:1:(info(2)-1)
                        if(app.m_chessboard.matrix(row+m,col+n) == 1)
                            not_approved = 1; 
                        end
                    end
                end
            else
                not_approved = 1;
            end
            
        end
        
        function Assign(app,row,col,code)
            info = MatchInfo(app,code);
            for i = 0:1:(info(1)-1)
                for j = 0:1:(info(2)-1)
                    bt = returnButton(app,row+i,col+j);
                    bt.Enable = 0;
                    app.m_chessboard.matrix(row+i,col+j) = 1;
                end
            end
            if(code == 1)
                app.m_chessboard.Cao_row = [row,row+1];
                app.m_chessboard.Cao_col = [col,col+1];
                app.Cao.Layout.Row = app.m_chessboard.Cao_row;
                app.Cao.Layout.Column = app.m_chessboard.Cao_col;
                app.Cao.Visible = 1;
            elseif(code == 2)
                app.m_chessboard.ZhangFei_row = [row,row+1];
                app.m_chessboard.ZhangFei_col = col;
                app.ZhangFei.Layout.Row = app.m_chessboard.ZhangFei_row;
                app.ZhangFei.Layout.Column = app.m_chessboard.ZhangFei_col;
                app.ZhangFei.Visible = 1;
            elseif(code == 3)
                app.m_chessboard.ZhaoYun_row = [row,row+1];
                app.m_chessboard.ZhaoYun_col = col; 
                app.ZhaoYun.Layout.Row = app.m_chessboard.ZhaoYun_row;
                app.ZhaoYun.Layout.Column = app.m_chessboard.ZhaoYun_col;
                app.ZhaoYun.Visible = 1;
            elseif(code == 4)
                app.m_chessboard.HuangZhong_row = [row,row+1];
                app.m_chessboard.HuangZhong_col = col;
                app.HuangZhong.Layout.Row = app.m_chessboard.HuangZhong_row;
                app.HuangZhong.Layout.Column = app.m_chessboard.HuangZhong_col;
                app.HuangZhong.Visible = 1;
            elseif(code == 5)
                app.m_chessboard.MaChao_row = [row,row+1];
                app.m_chessboard.MaChao_col = col;
                app.MaChao.Layout.Row = app.m_chessboard.MaChao_row;
                app.MaChao.Layout.Column = app.m_chessboard.MaChao_col;
                app.MaChao.Visible = 1;
            elseif(code == 6)
                app.m_chessboard.GuanYu_row = row;
                app.m_chessboard.GuanYu_col = [col,col+1];
                app.GuanYu.Layout.Row = app.m_chessboard.GuanYu_row;
                app.GuanYu.Layout.Column = app.m_chessboard.GuanYu_col;
                app.GuanYu.Visible = 1;
            elseif(code == 7)
                app.m_chessboard.Soldier_1_row = row;
                app.m_chessboard.Soldier_1_col = col;
                app.Soldier_1.Layout.Row = app.m_chessboard.Soldier_1_row;
                app.Soldier_1.Layout.Column = app.m_chessboard.Soldier_1_col;
                app.Soldier_1.Visible = 1;
            elseif(code == 8)
                app.m_chessboard.Soldier_2_row = row;
                app.m_chessboard.Soldier_2_col = col;
                app.Soldier_2.Layout.Row = app.m_chessboard.Soldier_2_row;
                app.Soldier_2.Layout.Column = app.m_chessboard.Soldier_2_col;
                app.Soldier_2.Visible = 1;
            elseif(code == 9)
                app.m_chessboard.Soldier_3_row = row;
                app.m_chessboard.Soldier_3_col = col;
                app.Soldier_3.Layout.Row = app.m_chessboard.Soldier_3_row;
                app.Soldier_3.Layout.Column = app.m_chessboard.Soldier_3_col;
                app.Soldier_3.Visible = 1;
            elseif(code == 10)
                app.m_chessboard.Soldier_4_row = row;
                app.m_chessboard.Soldier_4_col = col;
                app.Soldier_4.Layout.Row = app.m_chessboard.Soldier_4_row;
                app.Soldier_4.Layout.Column = app.m_chessboard.Soldier_4_col;
                app.Soldier_4.Visible = 1;
            end
            if(code == 10)
                app.StartGame.Enable = 1;
                for i=1:1:20
                    bt = app.Button_matrix(i);
                    bt.Enable = 0;
                end
                app.m_chessboard.Init_Chessboard = app.m_chessboard.matrix;
                %mat = app.m_chessboard.matrix
            end
        end
        
        function AssignLabel(app,code)
            if(code == 1)
                app.CurrentRole.Text = "当前角色: 曹操";
            elseif(code == 2)
                app.CurrentRole.Text = "当前角色: 张飞";
            elseif(code == 3)
                app.CurrentRole.Text = "当前角色: 赵云";
            elseif(code == 4)
                app.CurrentRole.Text = "当前角色: 黄忠";
            elseif(code == 5)
                app.CurrentRole.Text = "当前角色: 马超";
            elseif(code == 6)
                app.CurrentRole.Text = "当前角色: 关羽";
            elseif(code == 7)
                app.CurrentRole.Text = "当前角色: 卒1";
            elseif(code == 8)
                app.CurrentRole.Text = "当前角色: 卒2";
            elseif(code == 9)
                app.CurrentRole.Text = "当前角色: 卒3";
            elseif(code == 10)
                app.CurrentRole.Text = "当前角色: 卒4";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chessboard)
            app.m_chessboard = chessboard;
            app.Button_matrix = [app.B1_1,app.B1_2,app.B1_3,app.B1_4;
                                 app.B2_1,app.B2_2,app.B2_3,app.B2_4;
                                 app.B3_1,app.B3_2,app.B3_3,app.B3_4;
                                 app.B4_1,app.B4_2,app.B4_3,app.B4_4;
                                 app.B5_1,app.B5_2,app.B5_3,app.B5_4];
            app.m_chessboard.matrix = [0,0,0,0;
                          0,0,0,0;
                          0,0,0,0;
                          0,0,0,0;
                          0,0,0,0;];
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.chess_code = 1;
            app.CurrentRole.Text = "当前角色: 曹操";
            app.m_chessboard.matrix = [0,0,0,0;
                          0,0,0,0;
                          0,0,0,0;
                          0,0,0,0;
                          0,0,0,0;];
            for i=1:1:20
                    bt = app.Button_matrix(i);
                    bt.Enable = 1;
            end
            app.Cao.Visible = 0;
            app.ZhangFei.Visible = 0;
            app.ZhaoYun.Visible = 0;
            app.HuangZhong.Visible = 0;
            app.MaChao.Visible = 0;
            app.GuanYu.Visible = 0;
            app.Soldier_1.Visible = 0;
            app.Soldier_2.Visible = 0;
            app.Soldier_3.Visible = 0;
            app.Soldier_4.Visible = 0;
            app.StartGame.Enable = 0;
        end

        % Button pushed function: B1_1
        function B1_1ButtonPushed(app, event)
            if(Fail(app,1,1,app.chess_code) == 0)
                Assign(app,1,1,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B2_1
        function B2_1ButtonPushed(app, event)
            if(Fail(app,2,1,app.chess_code) == 0)
                Assign(app,2,1,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B3_1
        function B3_1ButtonPushed(app, event)
            if(Fail(app,3,1,app.chess_code) == 0)
                Assign(app,3,1,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B4_1
        function B4_1ButtonPushed(app, event)
            if(Fail(app,4,1,app.chess_code) == 0)
                Assign(app,4,1,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B5_1
        function B5_1ButtonPushed(app, event)
            if(Fail(app,5,1,app.chess_code) == 0)
                Assign(app,5,1,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B1_2
        function B1_2ButtonPushed(app, event)
            if(Fail(app,1,2,app.chess_code) == 0)
                Assign(app,1,2,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B2_2
        function B2_2ButtonPushed(app, event)
            if(Fail(app,2,2,app.chess_code) == 0)
                Assign(app,2,2,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B3_2
        function B3_2ButtonPushed(app, event)
            if(Fail(app,3,2,app.chess_code) == 0)
                Assign(app,3,2,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B4_2
        function B4_2ButtonPushed(app, event)
            if(Fail(app,4,2,app.chess_code) == 0)
                Assign(app,4,2,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B5_2
        function B5_2ButtonPushed(app, event)
            if(Fail(app,5,2,app.chess_code) == 0)
                Assign(app,5,2,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B1_3
        function B1_3ButtonPushed(app, event)
            if(Fail(app,1,3,app.chess_code) == 0)
                Assign(app,1,3,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B2_3
        function B2_3ButtonPushed(app, event)
            if(Fail(app,2,3,app.chess_code) == 0)
                Assign(app,2,3,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B3_3
        function B3_3ButtonPushed(app, event)
            if(Fail(app,3,3,app.chess_code) == 0)
                Assign(app,3,3,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B4_3
        function B4_3ButtonPushed(app, event)
            if(Fail(app,4,3,app.chess_code) == 0)
                Assign(app,4,3,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B5_3
        function B5_3ButtonPushed(app, event)
            if(Fail(app,5,3,app.chess_code) == 0)
                Assign(app,5,3,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B1_4
        function B1_4ButtonPushed(app, event)
            if(Fail(app,1,4,app.chess_code) == 0)
                Assign(app,1,4,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B2_4
        function B2_4ButtonPushed(app, event)
            if(Fail(app,2,4,app.chess_code) == 0)
                Assign(app,2,4,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B3_4
        function B3_4ButtonPushed(app, event)
            if(Fail(app,3,4,app.chess_code) == 0)
                Assign(app,3,4,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B4_4
        function B4_4ButtonPushed(app, event)
            if(Fail(app,4,4,app.chess_code) == 0)
                Assign(app,4,4,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: B5_4
        function B5_4ButtonPushed(app, event)
            if(Fail(app,5,4,app.chess_code) == 0)
                Assign(app,5,4,app.chess_code);
                app.chess_code = app.chess_code + 1;
                AssignLabel(app,app.chess_code);
            end
        end

        % Button pushed function: StartGame
        function StartGameButtonPushed(app, event)
            mainUI = mainInterface(app.m_chessboard, 1);
            app.m_chessboard.mainInterfaceUI = mainUI;
            delete(app);
        end

        % Button pushed function: RetMainMenu
        function RetMainMenuButtonPushed(app, event)
            Menu = mainMenu(app.m_chessboard);
            app.m_chessboard.mainMenu = Menu;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.B1_1 = uibutton(app.GridLayout, 'push');
            app.B1_1.ButtonPushedFcn = createCallbackFcn(app, @B1_1ButtonPushed, true);
            app.B1_1.Text = '';
            app.B2_1 = uibutton(app.GridLayout, 'push');
            app.B2_1.ButtonPushedFcn = createCallbackFcn(app, @B2_1ButtonPushed, true);
            app.B2_1.Text = '';
            app.B3_1 = uibutton(app.GridLayout, 'push');
            app.B3_1.ButtonPushedFcn = createCallbackFcn(app, @B3_1ButtonPushed, true);
            app.B3_1.Text = '';
            app.B4_1 = uibutton(app.GridLayout, 'push');
            app.B4_1.ButtonPushedFcn = createCallbackFcn(app, @B4_1ButtonPushed, true);
            app.B4_1.Text = '';
            app.B5_1 = uibutton(app.GridLayout, 'push');
            app.B5_1.ButtonPushedFcn = createCallbackFcn(app, @B5_1ButtonPushed, true);
            app.B5_1.Text = '';
            app.B1_2 = uibutton(app.GridLayout, 'push');
            app.B1_2.ButtonPushedFcn = createCallbackFcn(app, @B1_2ButtonPushed, true);
            app.B1_2.Text = '';
            app.B2_2 = uibutton(app.GridLayout, 'push');
            app.B2_2.ButtonPushedFcn = createCallbackFcn(app, @B2_2ButtonPushed, true);
            app.B2_2.Text = '';
            app.B3_2 = uibutton(app.GridLayout, 'push');
            app.B3_2.ButtonPushedFcn = createCallbackFcn(app, @B3_2ButtonPushed, true);
            app.B3_2.Text = '';
            app.B4_2 = uibutton(app.GridLayout, 'push');
            app.B4_2.ButtonPushedFcn = createCallbackFcn(app, @B4_2ButtonPushed, true);
            app.B4_2.Text = '';
            app.B5_2 = uibutton(app.GridLayout, 'push');
            app.B5_2.ButtonPushedFcn = createCallbackFcn(app, @B5_2ButtonPushed, true);
            app.B5_2.Text = '';
            app.B1_3 = uibutton(app.GridLayout, 'push');
            app.B1_3.ButtonPushedFcn = createCallbackFcn(app, @B1_3ButtonPushed, true);
            app.B1_3.Text = '';
            app.B2_3 = uibutton(app.GridLayout, 'push');
            app.B2_3.ButtonPushedFcn = createCallbackFcn(app, @B2_3ButtonPushed, true);
            app.B2_3.Text = '';
            app.B3_3 = uibutton(app.GridLayout, 'push');
            app.B3_3.ButtonPushedFcn = createCallbackFcn(app, @B3_3ButtonPushed, true);
            app.B3_3.Text = '';
            app.B4_3 = uibutton(app.GridLayout, 'push');
            app.B4_3.ButtonPushedFcn = createCallbackFcn(app, @B4_3ButtonPushed, true);
            app.B4_3.Text = '';
            app.B5_3 = uibutton(app.GridLayout, 'push');
            app.B5_3.ButtonPushedFcn = createCallbackFcn(app, @B5_3ButtonPushed, true);
            app.B5_3.Text = '';
            app.B1_4 = uibutton(app.GridLayout, 'push');
            app.B1_4.ButtonPushedFcn = createCallbackFcn(app, @B1_4ButtonPushed, true);
            app.B1_4.Text = '';
            app.B2_4 = uibutton(app.GridLayout, 'push');
            app.B2_4.ButtonPushedFcn = createCallbackFcn(app, @B2_4ButtonPushed, true);
            app.B2_4.Text = '';
            app.B3_4 = uibutton(app.GridLayout, 'push');
            app.B3_4.ButtonPushedFcn = createCallbackFcn(app, @B3_4ButtonPushed, true);
            app.B3_4.Text = '';
            app.B4_4 = uibutton(app.GridLayout, 'push');
            app.B4_4.ButtonPushedFcn = createCallbackFcn(app, @B4_4ButtonPushed, true);
            app.B4_4.Text = '';
            app.B5_4 = uibutton(app.GridLayout, 'push');
            app.B5_4.ButtonPushedFcn = createCallbackFcn(app, @B5_4ButtonPushed, true);
            app.B5_4.Text = '';
            app.StartGame = uibutton(app.UIFigure, 'push');
            app.StartGame.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGame.Enable = 'off';
            app.StartGame.Text = '开始';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '重置';
            app.CurrentRole = uilabel(app.UIFigure);
            app.CurrentRole.Text = '当前角色: 曹操';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Cao = uiimage(app.GridLayout2);
            app.Cao.Visible = 'off';
            app.Cao.ImageSource = 'Cao.png';
            app.ZhangFei = uiimage(app.GridLayout2);
            app.ZhangFei.Visible = 'off';
            app.ZhangFei.ImageSource = 'ZhangFei.png';
            app.ZhaoYun = uiimage(app.GridLayout2);
            app.ZhaoYun.Visible = 'off';
            app.ZhaoYun.ImageSource = 'ZhaoYun.png';
            app.HuangZhong = uiimage(app.GridLayout2);
            app.HuangZhong.Visible = 'off';
            app.HuangZhong.ImageSource = 'HuangZhong.png';
            app.MaChao = uiimage(app.GridLayout2);
            app.MaChao.Visible = 'off';
            app.MaChao.ImageSource = 'MaChao.png';
            app.GuanYu = uiimage(app.GridLayout2);
            app.GuanYu.Visible = 'off';
            app.GuanYu.ImageSource = 'GuanYu.png';
            app.Soldier_1 = uiimage(app.GridLayout2);
            app.Soldier_1.Visible = 'off';
            app.Soldier_1.ImageSource = 'Soldier.png';
            app.Soldier_2 = uiimage(app.GridLayout2);
            app.Soldier_2.Visible = 'off';
            app.Soldier_2.ImageSource = 'Soldier.png';
            app.Soldier_3 = uiimage(app.GridLayout2);
            app.Soldier_3.Visible = 'off';
            app.Soldier_3.ImageSource = 'Soldier.png';
            app.Soldier_4 = uiimage(app.GridLayout2);
            app.Soldier_4.Visible = 'off';
            app.Soldier_4.ImageSource = 'Soldier.png';
            app.RetMainMenu = uibutton(app.UIFigure, 'push');
            app.RetMainMenu.ButtonPushedFcn = createCallbackFcn(app, @RetMainMenuButtonPushed, true);
            app.RetMainMenu.Text = '返回主菜单';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selfMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end